classdef GIST_checkbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WasanyshapeunwelcomeCheckBox  matlab.ui.control.CheckBox
        DidanyshapefindanothershapescaryCheckBox  matlab.ui.control.CheckBox
        DidanyshapefindanothershapeprotectiveCheckBox  matlab.ui.control.CheckBox
        DidanyshapefindanothershapefriendlyCheckBox  matlab.ui.control.CheckBox
        DidanyshapedeceiveanothershapeCheckBox  matlab.ui.control.CheckBox
        DidanyshapefindanythingsurprisingCheckBox  matlab.ui.control.CheckBox
        DidanyshapefindanythingunexpectedCheckBox  matlab.ui.control.CheckBox
        argueCheckBox                 matlab.ui.control.CheckBox
        conversationCheckBox          matlab.ui.control.CheckBox
        emotionCheckBox_2             matlab.ui.control.CheckBox
        avoidCheckBox                 matlab.ui.control.CheckBox
        emotionCheckBox               matlab.ui.control.CheckBox
        hugCheckBox                   matlab.ui.control.CheckBox
        fleeCheckBox                  matlab.ui.control.CheckBox
        stalkCheckBox                 matlab.ui.control.CheckBox
        competitionCheckBox           matlab.ui.control.CheckBox
        cooperationCheckBox           matlab.ui.control.CheckBox
        attackCheckBox                matlab.ui.control.CheckBox
        helpCheckBox                  matlab.ui.control.CheckBox
        vibrateCheckBox               matlab.ui.control.CheckBox
        separationCheckBox            matlab.ui.control.CheckBox
        touchCheckBox                 matlab.ui.control.CheckBox
        chaseCheckBox                 matlab.ui.control.CheckBox
        approachCheckBox              matlab.ui.control.CheckBox
        PlayButton                    matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        WhichofthefollowingdidyouseeLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        videopath
        vid_ind = 1 % which video (index) to play
        keywords_allmovies = cell(0, 0) % 3rd output column
        mp4names % list of movie file names (without path) -> 1st output column
        mp4s % list of movie full file names
        nvideo % number of videos
        timesplayed_counter = 0
        timesplayed = [] % number of times each video was played -> 2nd output column
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run = 3; % 8 runs in total, similar to fMRI scans
            flipped = 1; % normal animation or flipped

            if flipped == 0
                mp4_root = strcat('stims/mp4/',num2str(run));
            else
                mp4_root = strcat('stims/mp4/flipped_',num2str(run));
            end
            items = dir(fullfile(mp4_root, '*.mp4'));
            % shuffle items to randomize videos within a folder
            randomIndices = randperm(numel(items));
            items = items(randomIndices);

            app.nvideo = numel(items);
            app.mp4s = cell(1, app.nvideo); % empty cell array
            app.mp4names = cell(1, app.nvideo); % empty cell array
            for i = 1:app.nvideo
                app.mp4s{i} = fullfile(mp4_root, items(i).name);
                app.mp4names{i} = items(i).name;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayButton.Enable = 'off';

            app.timesplayed_counter = app.timesplayed_counter + 1;

            app.videopath = app.mp4s{app.vid_ind};
            
            vidObj = VideoReader(app.videopath);
            %timer1 = tic;
            while(hasFrame(vidObj))
                timer2 = tic;
                frame = readFrame(vidObj);
                app.Image.ImageSource = frame;
                processtime = toc(timer2);
                pause((1/vidObj.FrameRate)-processtime);
            end
            %elapsed = toc(timer1);
            %disp(elapsed);
            app.NextButton.Enable = 'on';
            % enable the checkboxes here:
            app.approachCheckBox.Enable = "on";
            app.chaseCheckBox.Enable = "on";
            app.touchCheckBox.Enable = "on";
            app.separationCheckBox.Enable = "on";
            app.vibrateCheckBox.Enable = "on";
            app.helpCheckBox.Enable = "on";
            app.attackCheckBox.Enable = "on";
            app.cooperationCheckBox.Enable = "on";
            app.competitionCheckBox.Enable = "on";
            app.stalkCheckBox.Enable = "on";
            app.fleeCheckBox.Enable = "on";
            app.hugCheckBox.Enable = "on";
            app.emotionCheckBox.Enable = "on";
            app.avoidCheckBox.Enable = "on";
            app.emotionCheckBox_2.Enable = "on";
            app.conversationCheckBox.Enable = "on";
            app.argueCheckBox.Enable = "on";
            app.DidanyshapefindanythingunexpectedCheckBox.Enable = "on";
            app.DidanyshapefindanythingsurprisingCheckBox.Enable = "on";
            app.DidanyshapedeceiveanothershapeCheckBox.Enable = "on";
            app.DidanyshapefindanothershapefriendlyCheckBox.Enable = "on";
            app.DidanyshapefindanothershapeprotectiveCheckBox.Enable = "on";
            app.DidanyshapefindanothershapescaryCheckBox.Enable = "on";
            app.WasanyshapeunwelcomeCheckBox.Enable = "on";

            app.PlayButton.Enable = 'on';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % making a string from checkboxes, separated by comma
            words = '';
            if app.approachCheckBox.Value words = [words, 'approach, ']; end
            if app.chaseCheckBox.Value words = [words, 'chase, ']; end
            if app.touchCheckBox.Value words = [words, 'touch, ']; end
            if app.separationCheckBox.Value words = [words, 'separation, ']; end
            if app.vibrateCheckBox.Value words = [words, 'vibrate, ']; end
            if app.helpCheckBox.Value words = [words, 'help, ']; end
            if app.attackCheckBox.Value words = [words, 'attack, ']; end
            if app.cooperationCheckBox.Value words = [words, 'cooperation, ']; end
            if app.competitionCheckBox.Value words = [words, 'competition, ']; end
            if app.stalkCheckBox.Value words = [words, 'stalk, ']; end
            if app.fleeCheckBox.Value words = [words, 'flee, ']; end
            if app.hugCheckBox.Value words = [words, 'hug, ']; end
            if app.emotionCheckBox.Value words = [words, 'positive emotion, ']; end
            if app.avoidCheckBox.Value words = [words, 'avoid, ']; end
            if app.emotionCheckBox_2.Value words = [words, 'negative emotion, ']; end
            if app.conversationCheckBox.Value words = [words, 'conversation, ']; end
            if app.argueCheckBox.Value words = [words, 'argue, ']; end
            if app.DidanyshapefindanythingunexpectedCheckBox.Value words = [words, 'unexpected, ']; end
            if app.DidanyshapefindanythingsurprisingCheckBox.Value words = [words, 'surprising, ']; end
            if app.DidanyshapedeceiveanothershapeCheckBox.Value words = [words, 'deceive, ']; end
            if app.DidanyshapefindanothershapefriendlyCheckBox.Value words = [words, 'friendly, ']; end
            if app.DidanyshapefindanothershapeprotectiveCheckBox.Value words = [words, 'protective, ']; end
            if app.DidanyshapefindanothershapescaryCheckBox.Value words = [words, 'scary, ']; end
            if app.WasanyshapeunwelcomeCheckBox.Value words = [words, 'unwelcome, ']; end

            % remove the last ', ':
            if ~isempty(words)
                words = words(1:end-2);
            end

            app.keywords_allmovies{app.vid_ind} = words;
            

            %app.keywords_allmovies{app.vid_ind} = 
            app.timesplayed = [app.timesplayed app.timesplayed_counter];
            app.timesplayed_counter = 0;
            
            if app.vid_ind == app.nvideo
                fileID = fopen('gists_checkbox.tsv', 'w');
                
                % write the cell array to tsv file
                for cel = 1:size(app.keywords_allmovies, 2)
                    fprintf(fileID, '%s', app.mp4names{1, cel});
                    fprintf(fileID, '\t');
                    fprintf(fileID, num2str(app.timesplayed(cel)));
                    fprintf(fileID, '\t');
                    fprintf(fileID, '%s', app.keywords_allmovies{1, cel});
                    if cel < size(app.keywords_allmovies, 2)
                       fprintf(fileID, '\n'); 
                    end
                end
                
                fclose(fileID);

                app.PlayButton.Enable = 'off';
            end

            % uncheck all the checkboxes here:
            app.approachCheckBox.Value = 0;
            app.chaseCheckBox.Value = 0;
            app.touchCheckBox.Value = 0;
            app.separationCheckBox.Value = 0;
            app.vibrateCheckBox.Value = 0;
            app.helpCheckBox.Value = 0;
            app.attackCheckBox.Value = 0;
            app.cooperationCheckBox.Value = 0;
            app.competitionCheckBox.Value = 0;
            app.stalkCheckBox.Value = 0;
            app.fleeCheckBox.Value = 0;
            app.hugCheckBox.Value = 0;
            app.emotionCheckBox.Value = 0;
            app.avoidCheckBox.Value = 0;
            app.emotionCheckBox_2.Value = 0;
            app.conversationCheckBox.Value = 0;
            app.argueCheckBox.Value = 0;
            app.DidanyshapefindanythingunexpectedCheckBox.Value = 0;
            app.DidanyshapefindanythingsurprisingCheckBox.Value = 0;
            app.DidanyshapedeceiveanothershapeCheckBox.Value = 0;
            app.DidanyshapefindanothershapefriendlyCheckBox.Value = 0;
            app.DidanyshapefindanothershapeprotectiveCheckBox.Value = 0;
            app.DidanyshapefindanothershapescaryCheckBox.Value = 0;
            app.WasanyshapeunwelcomeCheckBox.Value = 0;

            app.vid_ind = app.vid_ind + 1;

            app.NextButton.Enable = 'off';
            % disable the checkboxes here:
            app.approachCheckBox.Enable = "off";
            app.chaseCheckBox.Enable = "off";
            app.touchCheckBox.Enable = "off";
            app.separationCheckBox.Enable = "off";
            app.vibrateCheckBox.Enable = "off";
            app.helpCheckBox.Enable = "off";
            app.attackCheckBox.Enable = "off";
            app.cooperationCheckBox.Enable = "off";
            app.competitionCheckBox.Enable = "off";
            app.stalkCheckBox.Enable = "off";
            app.fleeCheckBox.Enable = "off";
            app.hugCheckBox.Enable = "off";
            app.emotionCheckBox.Enable = "off";
            app.avoidCheckBox.Enable = "off";
            app.emotionCheckBox_2.Enable = "off";
            app.conversationCheckBox.Enable = "off";
            app.argueCheckBox.Enable = "off";
            app.DidanyshapefindanythingunexpectedCheckBox.Enable = "off";
            app.DidanyshapefindanythingsurprisingCheckBox.Enable = "off";
            app.DidanyshapedeceiveanothershapeCheckBox.Enable = "off";
            app.DidanyshapefindanothershapefriendlyCheckBox.Enable = "off";
            app.DidanyshapefindanothershapeprotectiveCheckBox.Enable = "off";
            app.DidanyshapefindanothershapescaryCheckBox.Enable = "off";
            app.WasanyshapeunwelcomeCheckBox.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhichofthefollowingdidyouseeLabel = uilabel(app.UIFigure);
            app.WhichofthefollowingdidyouseeLabel.Text = 'Which of the following did you see?';
            app.Image = uiimage(app.UIFigure);
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.approachCheckBox = uicheckbox(app.UIFigure);
            app.approachCheckBox.Enable = 'off';
            app.approachCheckBox.Text = 'approach';
            app.chaseCheckBox = uicheckbox(app.UIFigure);
            app.chaseCheckBox.Enable = 'off';
            app.chaseCheckBox.Text = 'chase';
            app.touchCheckBox = uicheckbox(app.UIFigure);
            app.touchCheckBox.Enable = 'off';
            app.touchCheckBox.Text = 'touch';
            app.separationCheckBox = uicheckbox(app.UIFigure);
            app.separationCheckBox.Enable = 'off';
            app.separationCheckBox.Text = 'separation';
            app.vibrateCheckBox = uicheckbox(app.UIFigure);
            app.vibrateCheckBox.Enable = 'off';
            app.vibrateCheckBox.Text = 'vibrate';
            app.helpCheckBox = uicheckbox(app.UIFigure);
            app.helpCheckBox.Enable = 'off';
            app.helpCheckBox.Text = 'help';
            app.attackCheckBox = uicheckbox(app.UIFigure);
            app.attackCheckBox.Enable = 'off';
            app.attackCheckBox.Text = 'attack';
            app.cooperationCheckBox = uicheckbox(app.UIFigure);
            app.cooperationCheckBox.Enable = 'off';
            app.cooperationCheckBox.Text = 'cooperation';
            app.competitionCheckBox = uicheckbox(app.UIFigure);
            app.competitionCheckBox.Enable = 'off';
            app.competitionCheckBox.Text = 'competition';
            app.stalkCheckBox = uicheckbox(app.UIFigure);
            app.stalkCheckBox.Enable = 'off';
            app.stalkCheckBox.Text = 'stalk';
            app.fleeCheckBox = uicheckbox(app.UIFigure);
            app.fleeCheckBox.Enable = 'off';
            app.fleeCheckBox.Text = 'flee';
            app.hugCheckBox = uicheckbox(app.UIFigure);
            app.hugCheckBox.Enable = 'off';
            app.hugCheckBox.Text = 'hug';
            app.emotionCheckBox = uicheckbox(app.UIFigure);
            app.emotionCheckBox.Enable = 'off';
            app.emotionCheckBox.Text = 'emotion (+)';
            app.avoidCheckBox = uicheckbox(app.UIFigure);
            app.avoidCheckBox.Enable = 'off';
            app.avoidCheckBox.Text = 'avoid';
            app.emotionCheckBox_2 = uicheckbox(app.UIFigure);
            app.emotionCheckBox_2.Enable = 'off';
            app.emotionCheckBox_2.Text = 'emotion (-)';
            app.conversationCheckBox = uicheckbox(app.UIFigure);
            app.conversationCheckBox.Enable = 'off';
            app.conversationCheckBox.Text = 'conversation';
            app.argueCheckBox = uicheckbox(app.UIFigure);
            app.argueCheckBox.Enable = 'off';
            app.argueCheckBox.Text = 'argue';
            app.DidanyshapefindanythingunexpectedCheckBox = uicheckbox(app.UIFigure);
            app.DidanyshapefindanythingunexpectedCheckBox.Enable = 'off';
            app.DidanyshapefindanythingunexpectedCheckBox.Text = 'Did any shape find anything unexpected?';
            app.DidanyshapefindanythingsurprisingCheckBox = uicheckbox(app.UIFigure);
            app.DidanyshapefindanythingsurprisingCheckBox.Enable = 'off';
            app.DidanyshapefindanythingsurprisingCheckBox.Text = 'Did any shape find anything surprising?';
            app.DidanyshapedeceiveanothershapeCheckBox = uicheckbox(app.UIFigure);
            app.DidanyshapedeceiveanothershapeCheckBox.Enable = 'off';
            app.DidanyshapedeceiveanothershapeCheckBox.Text = 'Did any shape deceive another shape?';
            app.DidanyshapefindanothershapefriendlyCheckBox = uicheckbox(app.UIFigure);
            app.DidanyshapefindanothershapefriendlyCheckBox.Enable = 'off';
            app.DidanyshapefindanothershapefriendlyCheckBox.Text = 'Did any shape find another shape friendly?';
            app.DidanyshapefindanothershapeprotectiveCheckBox = uicheckbox(app.UIFigure);
            app.DidanyshapefindanothershapeprotectiveCheckBox.Enable = 'off';
            app.DidanyshapefindanothershapeprotectiveCheckBox.Text = 'Did any shape find another shape protective?';
            app.DidanyshapefindanothershapescaryCheckBox = uicheckbox(app.UIFigure);
            app.DidanyshapefindanothershapescaryCheckBox.Enable = 'off';
            app.DidanyshapefindanothershapescaryCheckBox.Text = 'Did any shape find another shape scary?';
            app.WasanyshapeunwelcomeCheckBox = uicheckbox(app.UIFigure);
            app.WasanyshapeunwelcomeCheckBox.Enable = 'off';
            app.WasanyshapeunwelcomeCheckBox.Text = 'Was any shape unwelcome?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIST_checkbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end