classdef GIST_keyword < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Image       matlab.ui.control.Image
        DescribethenarrativeusingkeywordsLabel  matlab.ui.control.Label
        EditField   matlab.ui.control.EditField
        NextButton  matlab.ui.control.Button
        PlayButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        videopath
        vid_ind = 1 % which video (index) to play
        keywords_allmovies = cell(0, 0) % 3rd output column
        mp4names % list of movie file names (without path) -> 1st output column
        mp4s % list of movie full file names
        nvideo % number of videos
        timesplayed_counter = 0
        timesplayed = [] % number of times each video was played -> 2nd output column
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run = 3; % 8 runs in total, similar to fMRI scans
            flipped = 1; % normal animation or flipped

            if flipped == 0
                mp4_root = strcat('stims/mp4/',num2str(run));
            else
                mp4_root = strcat('stims/mp4/flipped_',num2str(run));
            end
            items = dir(fullfile(mp4_root, '*.mp4'));
            % shuffle items to randomize videos within a folder
            randomIndices = randperm(numel(items));
            items = items(randomIndices);

            app.nvideo = numel(items);
            app.mp4s = cell(1, app.nvideo); % empty cell array
            app.mp4names = cell(1, app.nvideo); % empty cell array
            for i = 1:app.nvideo
                app.mp4s{i} = fullfile(mp4_root, items(i).name);
                app.mp4names{i} = items(i).name;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayButton.Enable = 'off';

            app.timesplayed_counter = app.timesplayed_counter + 1;

            app.videopath = app.mp4s{app.vid_ind};
            
            vidObj = VideoReader(app.videopath);
            %timer1 = tic;
            while(hasFrame(vidObj))
                timer2 = tic;
                frame = readFrame(vidObj);
                app.Image.ImageSource = frame;
                processtime = toc(timer2);
                pause((1/vidObj.FrameRate)-processtime);
            end
            %elapsed = toc(timer1);
            %disp(elapsed);
            app.NextButton.Enable = 'on';
            app.EditField.Enable = 'on';

            app.PlayButton.Enable = 'on';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % reading participants' keywords from the textbox
            app.keywords_allmovies{app.vid_ind} = app.EditField.Value;
            app.timesplayed = [app.timesplayed app.timesplayed_counter];
            app.timesplayed_counter = 0;

            if app.vid_ind == app.nvideo
                fileID = fopen('gists.tsv', 'w');
                
                % write the cell array to tsv file
                for cel = 1:size(app.keywords_allmovies, 2)
                    fprintf(fileID, '%s', app.mp4names{1, cel});
                    fprintf(fileID, '\t');
                    fprintf(fileID, num2str(app.timesplayed(cel)));
                    fprintf(fileID, '\t');
                    fprintf(fileID, '%s', app.keywords_allmovies{1, cel});
                    if cel < size(app.keywords_allmovies, 2)
                       fprintf(fileID, '\n'); 
                    end
                end
                fclose(fileID); 

                app.PlayButton.Enable = 'off';
            end

            app.EditField.Value = "";

            app.vid_ind = app.vid_ind + 1;

            app.NextButton.Enable = 'off';
            app.EditField.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Enable = 'off';
            app.DescribethenarrativeusingkeywordsLabel = uilabel(app.UIFigure);
            app.DescribethenarrativeusingkeywordsLabel.Text = 'Describe the narrative using keywords:';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIST_keyword

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end